classdef Projekt_GUI_Przemyslaw_Rys_Mateusz_Kulig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        drogawkierunkuxmEditField       matlab.ui.control.EditField
        drogawkierunkuxmEditFieldLabel  matlab.ui.control.Label
        PodajprdkopocztkowLabel         matlab.ui.control.Label
        vmsEditField                    matlab.ui.control.NumericEditField
        vmsEditFieldLabel               matlab.ui.control.Label
        EnergiaprzedeVTextAreaLabel     matlab.ui.control.Label
        EnergiaprzedeVTextArea          matlab.ui.control.TextArea
        EnergiapoeVTextAreaLabel        matlab.ui.control.Label
        EnergiapoeVTextArea             matlab.ui.control.TextArea
        OdchyleniemTextAreaLabel        matlab.ui.control.Label
        OdchyleniemTextArea             matlab.ui.control.TextArea
        pushmeUwUButton                 matlab.ui.control.Button
        EVSlider                        matlab.ui.control.Slider
        EVSliderLabel                   matlab.ui.control.Label
        czstkaListBox                   matlab.ui.control.ListBox
        czstkaListBoxLabel              matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EVSlider
        function EVSliderValueChanged(app, event)

        end

        % Callback function
        function vmsSliderValueChanged(app, event)

        end

        % Value changed function: czstkaListBox
        function czstkaListBoxValueChanged(app, event)
        
        end

        % Button pushed function: pushmeUwUButton
        function pushmeUwUButtonPushed(app, event)
            value = app.czstkaListBox.Value; 

            hold(app.UIAxes, 'off');
            hold(app.UIAxes, 'on');

           %------------------------------------------------------------------------%
           if value=="elektron" 
                q=-1.602*1e-19;
                m=9.109*1e-31;
                yyy=(q*(app.EVSlider.Value)/(2*m*(app.vmsEditField.Value)^2));
                zero=sqrt(abs(-0.1*app.vmsEditField.Value^2*2*m/(q*app.EVSlider.Value)));     
                E=0.05*50/app.EVSlider.Value;
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           %                              ODCHYLENIE                                 % 
            a = yyy*zero^2;
            b = string(a);
            app.OdchyleniemTextArea.Value = b;
           %                                DROGA                                    %
            h=string(zero); 
            app.drogawkierunkuxmEditField.Value=h;
           %                            ENERGIA PRZED                                % 
            c = (1/1.602)*10^(19)*0.5*m*(app.vmsEditField.Value)^2;
            d = string(c);
            app.EnergiaprzedeVTextArea.Value = d;
           %                              ENERGIA PO                                 % 
            e = c + abs(q*app.EVSlider.Value*zero/(m*app.vmsEditField.Value));
            f = string(e);
            app.EnergiapoeVTextArea.Value = f;
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                 hold(app.UIAxes, 'off');
                 app.UIAxes.XLim=[0 zero];
                 app.UIAxes.YLim=[0 0.2];
                 w=[0:E:0.2];
                 we=0:zero;
                        plot(app.UIAxes,we,0.2);
                        hold(app.UIAxes, 'on');
                   for j=0:zero/5:zero
                        plot(app.UIAxes,j,w,'r ^');
                        hold(app.UIAxes, 'on');
                   end
                app.UIAxes.YLim=[0 0.2];
                for i=0:0.01:zero
                    x=0:0.001:i;
                    y=0.1+x.^2*yyy;
                    plot(app.UIAxes,x,y,"blue");
                    pause(0.001);
                end
                 hold(app.UIAxes, 'off');

           %------------------------------------------------------------------------%
           elseif value=="proton"
                q=1.602*1e-19;
                m=1.672*1e-27;
                yyy=(q*(app.EVSlider.Value)/(2*m*(app.vmsEditField.Value)^2));
                zero2=sqrt(abs(0.1*app.vmsEditField.Value^2*2*m/(q*app.EVSlider.Value)));
                E=0.05*50/app.EVSlider.Value;

           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           %                              ODCHYLENIE                                 %  
            a = yyy*zero2^2;
            b = string(a);
            app.OdchyleniemTextArea.Value = b;
           %                                DROGA                                    %
            g=string(zero2); 
            app.drogawkierunkuxmEditField.Value=g;
           %                            ENERGIA PRZED                                %
            c = (1/1.602)*10^(19)*0.5*m*(app.vmsEditField.Value)^2;
            d = string(c);
            app.EnergiaprzedeVTextArea.Value = d;
           %                              ENERGIA PO                                 %
            e = c + abs(q*app.EVSlider.Value*zero2/(m*app.vmsEditField.Value));
            f = string(e);
            app.EnergiapoeVTextArea.Value = f;
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                 hold(app.UIAxes, 'off');
           app.UIAxes.XLim=[0 zero2];
                app.UIAxes.YLim=[0 0.2];
                w=[0:E:0.2];
                we=0:zero2;
                        plot(app.UIAxes,we,0.2);
                        hold(app.UIAxes, 'off');
                   for j=0:zero2/5:zero2
                        plot(app.UIAxes,j,w,'r ^');
                        hold(app.UIAxes, 'on');
                   end
                app.UIAxes.YLim=[0 0.2];
                for i=0:0.01:zero2
                    x=0:0.001:i;
                    y=0.1+x.^2*yyy;
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,x,y,"red");
                    pause(0.001);
                end   
                hold(app.UIAxes, 'off');
           %------------------------------------------------------------------------%
           elseif value=="neutron"
                m=1.672*1e-27;
                yyy=0;
                E=0.05*50/app.EVSlider.Value;
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           %                            ODCHYLENIE                                   % 
            a=string(0);
            app.OdchyleniemTextArea.Value = a;
           %                                DROGA                                    %
           app.drogawkierunkuxmEditField.Value=" ∞ (cząsta nie uderzy w żadną okładkę)";
           %                            ENERGIA PRZED                                % 
            c = (1/1.602)*10^(19)*0.5*m*(app.vmsEditField.Value).^2;
            d = string(c);
            app.EnergiaprzedeVTextArea.Value = d;
           %                            ENERGIA PO                                   % 
            app.EnergiapoeVTextArea.Value = d;
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                 hold(app.UIAxes, 'off');
                app.UIAxes.XLim=[0 1];
                app.UIAxes.YLim=[0 0.2];
                w=[0:E:0.2];
                we=[0 1];
                        hold(app.UIAxes, 'off');
                        plot(app.UIAxes,we,0.2);
                        hold(app.UIAxes, 'on');
                    for j=0:0.2:1
                        plot(app.UIAxes,j,w,'r ^');
                        hold(app.UIAxes, 'on');
                    end
                app.UIAxes.YLim=[0 0.2];
                for i=0:0.01:1
                    x=0:0.001:i;
                    y=0.1+x.^2*yyy;
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,x,y,"black");
                    pause(0.001);
                end   
                hold(app.UIAxes, 'off');
           end
        end

        % Callback function
        function wychylenieEditFieldValueChanged(app, event)

        end

        % Value changed function: OdchyleniemTextArea
        function OdchyleniemTextAreaValueChanged(app, event)

        end

        % Value changed function: EnergiaprzedeVTextArea
        function EnergiaprzedeVTextAreaValueChanged(app, event)

        end

        % Value changed function: EnergiapoeVTextArea
        function EnergiapoeVTextAreaValueChanged(app, event)

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
         
        end

        % Value changed function: vmsEditField
        function vmsEditFieldValueChanged(app, event)

        end

        % Value changed function: drogawkierunkuxmEditField
        function drogawkierunkuxmEditFieldValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Tor cząstki w naładowanym '; 'kondensatorze'})
            xlabel(app.UIAxes, 'X [m]')
            ylabel(app.UIAxes, 'Y [m]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [0 0.2];
            app.UIAxes.XTick = 0;
            app.UIAxes.YTick = [0 0.05 0.1 0.15 0.2];
            app.UIAxes.YTickLabel = {'0'; '0.05'; '0.1'; '0.15'; '0.2'};
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.czstkaListBoxLabel = uilabel(app.UIFigure);
            app.czstkaListBoxLabel.Text = {'cząstka'; ''};
            app.czstkaListBox = uilistbox(app.UIFigure);
            app.czstkaListBox.Items = {'elektron', 'proton', 'neutron'};
            app.czstkaListBox.ValueChangedFcn = createCallbackFcn(app, @czstkaListBoxValueChanged, true);
            app.czstkaListBox.Value = 'elektron';
            app.EVSliderLabel = uilabel(app.UIFigure);
            app.EVSliderLabel.Text = 'E [V]';
            app.EVSlider = uislider(app.UIFigure);
            app.EVSlider.ValueChangedFcn = createCallbackFcn(app, @EVSliderValueChanged, true);
            app.EVSlider.Value = 50;
            app.pushmeUwUButton = uibutton(app.UIFigure, 'push');
            app.pushmeUwUButton.ButtonPushedFcn = createCallbackFcn(app, @pushmeUwUButtonPushed, true);
            app.pushmeUwUButton.Text = {'push me'; 'UwU'; ''};
            app.OdchyleniemTextArea = uitextarea(app.UIFigure);
            app.OdchyleniemTextArea.ValueChangedFcn = createCallbackFcn(app, @OdchyleniemTextAreaValueChanged, true);
            app.OdchyleniemTextArea.Editable = 'off';
            app.OdchyleniemTextAreaLabel = uilabel(app.UIFigure);
            app.OdchyleniemTextAreaLabel.Text = 'Odchylenie [m]';
            app.EnergiapoeVTextArea = uitextarea(app.UIFigure);
            app.EnergiapoeVTextArea.ValueChangedFcn = createCallbackFcn(app, @EnergiapoeVTextAreaValueChanged, true);
            app.EnergiapoeVTextArea.Editable = 'off';
            app.EnergiapoeVTextAreaLabel = uilabel(app.UIFigure);
            app.EnergiapoeVTextAreaLabel.Text = 'Energia po [eV]';
            app.EnergiaprzedeVTextArea = uitextarea(app.UIFigure);
            app.EnergiaprzedeVTextArea.ValueChangedFcn = createCallbackFcn(app, @EnergiaprzedeVTextAreaValueChanged, true);
            app.EnergiaprzedeVTextArea.Editable = 'off';
            app.EnergiaprzedeVTextAreaLabel = uilabel(app.UIFigure);
            app.EnergiaprzedeVTextAreaLabel.Text = 'Energia przed [eV]';
            app.vmsEditFieldLabel = uilabel(app.UIFigure);
            app.vmsEditFieldLabel.Text = 'v [m/s]';
            app.vmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.vmsEditField.Limits = [0 300000000000];
            app.vmsEditField.ValueChangedFcn = createCallbackFcn(app, @vmsEditFieldValueChanged, true);
            app.vmsEditField.Value = 10000000;
            app.PodajprdkopocztkowLabel = uilabel(app.UIFigure);
            app.PodajprdkopocztkowLabel.Text = 'Podaj prędkość początkową:';
            app.drogawkierunkuxmEditFieldLabel = uilabel(app.UIFigure);
            app.drogawkierunkuxmEditFieldLabel.Text = 'droga w kierunku x [m]';
            app.drogawkierunkuxmEditField = uieditfield(app.UIFigure, 'text');
            app.drogawkierunkuxmEditField.ValueChangedFcn = createCallbackFcn(app, @drogawkierunkuxmEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Projekt_GUI_Przemyslaw_Rys_Mateusz_Kulig

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end